classdef Omar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        RGBTOGRAYButton  matlab.ui.control.Button
        APPLYButton      matlab.ui.control.Button
        Input1EditField  matlab.ui.control.NumericEditField
        Input2EditField  matlab.ui.control.NumericEditField
        chooseDropDown   matlab.ui.control.DropDown
        RESETButton      matlab.ui.control.Button
        UPLOADButton     matlab.ui.control.Button
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes_1         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        originalImage % Description
        editedImage
        resetImage
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UPLOADButton
        function UPLOADButtonPushed(app, event)
            global image;
            global arr;
            global counter;

            [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
            cd(filepath1);
            image=imread([filepath1 filename1]);
            counter=1;
            arr = cell(1,100);
            app.UIAxes_1.Visible = 'on';
            imshow(image, 'Parent', app.UIAxes_1);
            arr{counter}=image;

        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            global arr;
            global counter;
            global img;
            global val1;
            global val2;
            Feature=app.chooseDropDown.Value;
            function [outImage] = subSampling(image, subSamplingFactor)
                [rows cols] = size(image);
                outImage = image(1:subSamplingFactor:rows,1:subSamplingFactor:cols);
            end
            val1=app.Input1EditField.Value;
            val2=app.Input2EditField.Value;
            switch Feature
                case "Upsampling"
                    newImage = arr{counter};
                    upsampleFactor = val1;
                    [rows, cols, channels] = size(newImage);
                    rows
                    cols
                    outRows = rows * upsampleFactor;
                    outCols = cols * upsampleFactor;
                    outImage = zeros(outRows, outCols, channels, class(newImage));
                    for k = 1:channels
                        for i = 1:outRows
                            for j = 1:outCols
                                inputRow = round(i/upsampleFactor);
                                inputCol = round(j/upsampleFactor);
                                inputRow = min(max(inputRow, 1), rows);
                                inputCol = min(max(inputCol, 1), cols);
                                outImage(i, j, k) = newImage(inputRow, inputCol, k);
                            end
                        end
                    end
                    [rows, cols] = size(outImage);
                    rows
                    cols
                    imshow(outImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=outImage;
                case "Subsampling"
                    newImage = arr{counter};
                    [rows, cols, matricesNo] = size(newImage);
                    SamplingFactor = val1;
                    rows
                    cols
                    for metricesIndex=1:1:matricesNo
                        resizedImage(:,:,metricesIndex) = subSampling(newImage(:,:,metricesIndex),SamplingFactor);
                    end
                    [rows, cols] = size(resizedImage);
                    rows
                    cols
                    imshow(resizedImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=resizedImage;
                case "negative"
                    neg = 255 - arr{counter};
                    imshow(neg, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=neg;
                case "Log"
                    a=arr{counter};
                    log_I = uint8(log(double(a)+1) .* ((255)/log(256)));
                    imshow(log_I, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=log_I;
                case "inverse Log"
                    a=arr{counter};
                    exp_I = uint8((exp(double(a)) .^ (log(256) / (255))) - 1);
                    imshow(exp_I, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=exp_I;
                case "Power Low"
                    c = val1;
                    p = val2;
                    x1 = double(arr{counter});
                    y = c * power(x1, p);
                    output_img = uint8(y);
                    imshow(output_img, 'Parent', app.UIAxes_2);
                    arr{counter}=output_img;

                case "Contrast stretching"
                    a = arr{counter};
                    c =val1;
                    p = val2;
                    [rows, cols] = size(a);
                    maxx=max(max(a(:,:)));
                    minn=min(min(a(:,:)));
                    out=a;
                    for i = 1:rows
                        for j = 1:cols
                            out(i,j)=((p-c)/(maxx-minn))*(a(i,j)-minn)+c;
                        end
                    end
                    counter=counter+1;
                    arr{counter}=out;
                    imshow((out),'Parent',app.UIAxes_2);
                case "Threshould 0,1"
                    newImage = arr{counter};
                    T= val1;
                    [rows, cols] = size(newImage);
                    newImage=double(newImage);
                    for row_index=1:1:rows
                        for col_index=1:1:cols
                            if(newImage(row_index,col_index) >= T)
                                newImage(row_index,col_index) = 255.0;
                            else
                                newImage(row_index,col_index) = 0.0;
                            end
                        end
                    end
                    imshow(newImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=newImage;
                case 'Gray Level Resolution'
                    editedImg=arr{counter};
                    k = 8;
                    figure
                    while (k > 0)
                        target_levels = 2^k;
                        target_compr_factor = 256 / target_levels;
                        img = uint8(floor(double(editedImg)/256 * target_levels) * target_compr_factor);
                        subplot(3, 3, k);
                        imshow(img, [0 255]);
                        title(['Grey-level resolution 2^',num2str(k)]);
                        k = k - 1;
                    end

                case " Gray slicing AP#1"
                    newImage = arr{counter};
                    sm= val1;
                    sM= val2;
                    [rows, cols] = size(newImage);
                    for row_index=1:1:rows
                        for col_index=1:1:cols
                            if(newImage(row_index,col_index)>=sm && newImage(row_index,col_index)<=sM)
                                newImage(row_index,col_index) = 255;
                            else
                                newImage(row_index,col_index) = 0;
                            end
                        end
                    end
                    imshow(newImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=newImage;
                case " Gray slicing AP#2"
                    newImage = arr{counter};
                    sm= val1;
                    sM= val2;
                    [rows, cols] = size(newImage);
                    for row_index=1:1:rows
                        for col_index=1:1:cols
                            if(newImage(row_index,col_index)>=sm && newImage(row_index,col_index)<=sM)
                                newImage(row_index,col_index) = 255;
                            else
                                newImage(row_index,col_index) = newImage(row_index,col_index);
                            end
                        end
                    end
                    imshow(newImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=newImage;
                case "Bit plane slicing"
                    editedImg = arr{counter};
                    [rows, cols] = size(editedImg);
                    x = zeros(rows,cols,8);
                    for k=1:8
                        for row_index=1:1:rows
                            for col_index=1:1:cols
                                x(row_index,col_index,k)=bitget(editedImg(row_index,col_index),k);
                            end
                        end
                        subplot(3, 3, k);
                        imshow(x(:,:,k));
                        title(['Image for bit number ', num2str(k)]);

                    end
                case "Addition"
                    Image1 = arr{counter};
                    ad = val1;
                    answer = Image1 + ad;
                    imshow(answer,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=answer;
                case "Subtraction "
                    Image1 = arr{counter};
                    ad = val1;
                    answer = Image1-ad;
                    imshow(answer,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=answer;
                case "Multiplication"
                    Image1 = arr{counter};
                    ad = val1;
                    answer = Image1 * ad;
                    imshow(answer,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=answer;
                case "Division"
                    Image1 = arr{counter};
                    ad = val1;
                    answer = Image1 / ad;
                    imshow(answer,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=answer;
                case "Image Subtraction " %16

                    [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
                    cd(filepath1);
                    e=imread([filepath1 filename1]);
                    Image1 = arr{counter};
                    try
                        Image2 = rgb2gray(e);
                    catch
                    end
                    f=imresize(Image1,[size(Image2,1) size(Image2,2)]);
                    answer = Image2-f;
                    a=answer;
                    imshow(a,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;

                case 'Logic AND'
                    [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
                    cd(filepath1);
                    image1=imread([filepath1 filename1]);
                    image2=arr{counter};
                    image1=rgb2gray(image1);
                    image2=rgb2gray(image2);
                    [m, n] = size(image2);
                    and_image = zeros(m, n, 'uint8');
                    for i = 1:m
                        for j =1 : n
                            and_image(i,j) = bitand( image1(i,j), image2(i,j));
                        end
                    end
                    counter=counter+1;
                    arr{counter}=and_image;
                    imshow(and_image,'Parent',app.UIAxes_2);
                case 'Logic OR'
                    [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
                    cd(filepath1);
                    image1=imread([filepath1 filename1]);
                    image2=arr{counter};
                    [m, n] = size(image1);
                    [mm, nn] = size(image2);
                    new = image2;
                    for i = 1:min(mm,m)
                        for j =1:min(nn,n)
                            if (image2(i,j)>image1(i,j))
                                new(i,j)=image2(i,j);
                            else
                                new(i,j)=image1(i,j);
                            end
                        end
                    end
                    counter=counter+1;
                    arr{counter}=new;
                    imshow(new,'Parent',app.UIAxes_2);
                case 'Logic AND 0'
                    image2=arr{counter};
                    [m, n] = size(image2);
                    new = image2;
                    for i = 1:m
                        for j =1:n
                            new(i,j)=0;
                        end
                    end
                    counter=counter+1;
                    arr{counter}=new;
                    imshow(new,'Parent',app.UIAxes_2);
                case 'Logic AND 1 / OR 0'
                    image1=arr{counter};
                    counter=counter+1;
                    arr{counter}=image1;
                    imshow(image1,'Parent',app.UIAxes_2);
                case 'Logic OR 1'
                    image2=arr{counter};
                    [m, n] = size(image2);
                    new = image2;
                    for i = 1:m
                        for j =1:n
                            if(image2(i,j)<35)
                                new(i,j)=255;
                            end
                        end
                    end
                    counter=counter+1;
                    arr{counter}=new;
                    imshow(new,'Parent',app.UIAxes_2);
                case "Histogram" %19
                    %global a;
                    a=arr{counter};
                    [rows,cols]=size(a);
                    counts=zeros(1,256);
                    for i=1:rows
                        for j=1:cols
                            grayLevel=a(i,j);
                            counts(grayLevel+1)=counts(grayLevel+1)+1;
                        end
                    end
                    grayLevels = 0 : 255;
                    bar(grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'b');
                    title('Histogram');
                    xlabel('Gray Level');
                    ylabel('Counts');
                case "Histogram Equalization"
                    a=arr{counter};
                    numofpixels=size(a,1)*size(a,2);
                    HIm=uint8(zeros(size(a,1),size(a,2)));
                    freq=zeros(256,1);
                    probc=zeros(256,1);
                    output=zeros(256,1);
                    %freq counts the occurrence of each pixel value.
                    %The probability of each occurrence is calculated by probf.
                    for i=1:size(a,1)
                        for j=1:size(a,2)
                            value=a(i,j);
                            freq(value+1)=freq(value+1)+1;
                        end
                    end
                    summ=0;
                    no_bins=255;
                    %The cumulative distribution probability is calculated.
                    for i=1:size(freq)
                        summ=summ+freq(i);
                        probc(i)=summ/numofpixels;
                        output(i)=round(probc(i)*no_bins);
                    end
                    for i=1:size(a,1)
                        for j=1:size(a,2)
                            HIm(i,j)=output(a(i,j)+1);
                        end
                    end
                    a=HIm;
                    imshow((a),'Parent',app.UIAxes_2)
                    counter=counter+1;
                    arr{counter}=a;

                case "Smoothing(Avg Standerd)"
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    out = gray_image;
                    for i=2:rows-1
                        for j=2:cols-1
                            temp = gray_image(i-1:i+1,j-1:j+1).*(1/(3*3));
                            value = sum(temp(:));
                            out(i, j)= value;
                        end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;

                case "Smoothing(Avg Weight)" 
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    mask = [(1/16),(2/16),(1/16);(2/16),(4/16),(2/16);(1/16),(2/16),(1/16)];
                    out = gray_image;
                    for i=2:rows-1
                        for j=2:cols-1
                            temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                            value =sum(temp(:));
                            out(i, j)= value;
                        end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;
                case "Smoothing(Median)" 
                    a=arr{counter};
                    [rows,cols]=size(a);
                    out=a;
                    for i=2:rows-1
                        for j=2:cols-1
                            temp = a(i-1:i+1, j-1:j+1);
                            temp = sort(temp);
                            out(i, j)= temp(5);
                        end
                    end
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;
                case "Smoothing(Mod)"
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    out = gray_image;
                    for i=2:rows-1
                        for j=2:cols-1
                            neighborhood = gray_image(i-1:i+1, j-1:j+1);
                            out(i, j) = mode(neighborhood(:));
                        end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;
                case "Sharpining(Laplacian)" 
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    mask = [0,1,0;1,-4,1;0,1,0];
                    out = gray_image;
                    for i=2:rows-1
                        for j=2:cols-1
                            temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                            value = sum(temp(:));
                            out(i, j)= value+gray_image(i,j);
                        end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2)
                    counter=counter+1;
                    arr{counter}=a;

                case "Sharpining(Comp Laplacian)" %24
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    mask = [0,-1,0;-1,5,-1;0,-1,0];
                    out = gray_image;
                    for i=2:rows-1
                        for j=2:cols-1
                            temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                            value = sum(temp(:));
                            out(i, j)= value;
                        end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2)
                    counter=counter+1;
                    arr{counter}=a;

                case "Sharpining(Robert Vertical)" %25
                    input_image = arr{counter};

                    % Convert the image to double
                    input_image = double(input_image);

                    % Pre-allocate the filtered_image matrix with zeros
                    filtered_image = zeros(size(input_image));

                    % Robert Operator Mask
                    My = [0 -1;1 0];

                    for i = 1:size(input_image, 1) - 1
                        for j = 1:size(input_image, 2) - 1

                            % Gradient approximations
                            Gy = sum(sum(My.*input_image(i:i+1, j:j+1)));

                            % Calculate magnitude of vector
                            filtered_image(i, j) = sqrt(Gy.^2);
                        end
                    end

                    % Displaying Filtered Image
                    filtered_image = uint8(filtered_image);
                    imshow(filtered_image, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=filtered_image;
                case "Sharpining(Robert Horizontal)"
                    input_image = arr{counter};

                    % Convert the image to double
                    input_image = double(input_image);

                    % Pre-allocate the filtered_image matrix with zeros
                    filtered_image = zeros(size(input_image));

                    % Robert Operator Mask
                    Mx = [1 0; 0 -1];

                    for i = 1:size(input_image, 1) - 1
                        for j = 1:size(input_image, 2) - 1

                            % Gradient approximations
                            Gx = sum(sum(Mx.*input_image(i:i+1, j:j+1)));

                            % Calculate magnitude of vector
                            filtered_image(i, j) = sqrt(Gx.^2);
                        end
                    end
                    % Displaying Filtered Image
                    filtered_image = uint8(filtered_image);
                    imshow(filtered_image, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=filtered_image;
                case "Sharpening Robert frist dervite "
                    input_image = arr{counter};

                    % Convert the image to double
                    input_image = double(input_image);

                    % Pre-allocate the filtered_image matrix with zeros
                    filtered_image = zeros(size(input_image));

                    % Robert Operator Mask
                    Mx = [1 0; 0 -1];
                    My = [0 1; -1 0];

                    for i = 1:size(input_image, 1) - 1
                        for j = 1:size(input_image, 2) - 1

                            % Gradient approximations
                            Gx = sum(sum(Mx.*input_image(i:i+1, j:j+1)));
                            Gy = sum(sum(My.*input_image(i:i+1, j:j+1)));

                            % Calculate magnitude of vector
                            filtered_image(i, j) = sqrt(Gx.^2 + Gy.^2);

                        end
                    end
                    % Displaying Filtered Image
                    filtered_image = uint8(filtered_image);
                    imshow(filtered_image, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=filtered_image;
                case "Sharpining(Sobel Vertical)"
                    I=double(arr{counter});
                    Out=I;
                    [rows,cols]=size(I);
                    mask = [-1 0 1;-2 0 2;-1 0 1];
                    for i=2:rows-1
                        for j=2:cols-1
                            temp = mask.*I(i-1:i+1,j-1:j+1);
                            val=sum(temp(:));
                            %using max function for detection of final edges
                            Out(i, j)=val;
                        end
                    end
                    Out = uint8(Out);
                    imshow(Out, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=Out;
                case "Sharpining(Sobel Horizontal)"
                    I=double(arr{counter});
                    Out=I;
                    [rows,cols]=size(I);
                    mask = [-1 -2 -1;0 0 0;1 2 1];
                    for i=2:rows-1
                        for j=2:cols-1
                            temp = mask.*I(i-1:i+1,j-1:j+1);
                            val=sum(temp(:));
                            %using max function for detection of final edges
                            Out(i, j)=val;
                        end
                    end
                    Out = uint8(Out);
                    imshow(Out, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=Out;
                case "Sharpening Sobel frist dervite " 
                    I=double(arr{counter});
                    In=I;
                    mask1 = [-1 -2 -1;0 0 0;1 2 1];
                    mask2 = [-1 0 1;-2 0 2;-1 0 1];

                    for i=2:size(I, 1)-1
                        for j=2:size(I, 2)-1
                            neighbour_matrix1=mask1.*In(i-1:i+1, j-1:j+1);
                            avg_value1=sum(neighbour_matrix1(:));

                            neighbour_matrix2=mask2.*In(i-1:i+1, j-1:j+1);
                            avg_value2=sum(neighbour_matrix2(:));

                            %using max function for detection of final edges
                            I(i, j)=max([avg_value1, avg_value2]);

                        end
                    end
                    I = uint8(I);
                    imshow(I, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=I;
                case "High pass filter (ideal both)" 
                    input_image = arr{counter};
                    [M, N] = size(input_image);
                    FT_img = fft2(double(input_image));
                    D0 = val1;
                    u = 0:(M-1);
                    idx = find(u>M/2);
                    u(idx) = u(idx)-M;
                    v = 0:(N-1);
                    idy = find(v>N/2);
                    v(idy) = v(idy)-N;

                    [V, U] = meshgrid(v, u);

                    % Calculating Euclidean Distance
                    D = sqrt(U.^2+V.^2);

                    % Comparing with the cut-off frequency and
                    % determining the filtering mask
                    H = double(D > D0);
                    G = H.*FT_img;
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;
                case "High pass filter (Butterworth both)" %31
                    input_image = arr{counter};
                    [M, N] = size(input_image);
                    FT_img = fft2(double(input_image));
                    D0 = val1;
                    u = 0:(M-1);
                    idx = find(u>M/2);
                    u(idx) = u(idx)-M;
                    v = 0:(N-1);
                    idy = find(v>N/2);
                    v(idy) = v(idy)-N;

                    [V, U] = meshgrid(v, u);

                    % Calculating Euclidean Distance
                    D = sqrt(U.^2+V.^2);

                     D = D0./ D;
                    % determining the filtering mask
                    H = double(1./((1+D).^2));
                    G = H.*FT_img;
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;
                case "High pass filter (gaussian both)" %32
                   input_image = arr{counter};
                    [M, N] = size(input_image);
                    FT_img = fft2(double(input_image));
                    D0 = val1;
                    D0 = (D0^2)*2;
                    u = 0:(M-1);
                    idx = find(u>M/2);
                    u(idx) = u(idx)-M;
                    v = 0:(N-1);
                    idy = find(v>N/2);
                    v(idy) = v(idy)-N;

                    [V, U] = meshgrid(v, u);

                    % Calculating Euclidean Distance
                    D = sqrt(U.^2+V.^2);

                      D = -D.^2;
                      H = double(1-exp(D/D0));
                    G = H.*FT_img;
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;
                case "Low pass filter (ideal both)" 
                    input_image = arr{counter};
                    [M, N] = size(input_image);
                    FT_img = fft2(double(input_image));
                    D0 = val1;
                    u = 0:(M-1);
                    idx = find(u>M/2);
                    u(idx) = u(idx)-M;
                    v = 0:(N-1);
                    idy = find(v>N/2);
                    v(idy) = v(idy)-N;

                    [V, U] = meshgrid(v, u);

                    % Calculating Euclidean Distance
                    D = sqrt(U.^2+V.^2);

                    % Comparing with the cut-off frequency and
                    % determining the filtering mask
                    H = double(D <= D0);
                    G = H.*FT_img;

                    output_image = real(ifft2(double(G)));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;
                case "Low pass filter (Butterworth both)" %31
                    input_image = arr{counter};
                    [M, N] = size(input_image);
                    FT_img = fft2(double(input_image));
                    D0 = val1;
                    u = 0:(M-1);
                    idx = find(u>M/2);
                    u(idx) = u(idx)-M;
                    v = 0:(N-1);
                    idy = find(v>N/2);
                    v(idy) = v(idy)-N;

                    [V, U] = meshgrid(v, u);

                    % Calculating Euclidean Distance
                    D = sqrt(U.^2+V.^2);

                    D = D./ D0;
                    % determining the filtering mask
                    H = double(1./((1+D).^2));
                    G = H.*FT_img;
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;
                case "Low pass filter (gaussian both)" %32
                    input_image = arr{counter};
                    [M, N] = size(input_image);
                    FT_img = fft2(double(input_image));
                    D0 = val1;
                    D0 = (D0^2)*2;
                    u = 0:(M-1);
                    idx = find(u>M/2);
                    u(idx) = u(idx)-M;
                    v = 0:(N-1);
                    idy = find(v>N/2);
                    v(idy) = v(idy)-N;

                    [V, U] = meshgrid(v, u);

                    % Calculating Euclidean Distance
                    D = sqrt(U.^2+V.^2);
                    
                      D = -D.^2;
                      H = double (exp(D/D0));
                    G = H.*FT_img;
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;
            end

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            global arr;
            global counter;
            if(counter>=2)
                arr{counter}=0;
                counter = counter-1;
                imshow(arr{counter}, 'Parent', app.UIAxes_2);
                if(counter>1)
                    imshow(arr{counter-1}, 'Parent', app.UIAxes_1);
                end
            end
        end

        % Button pushed function: RGBTOGRAYButton
        function RGBTOGRAYButtonPushed(app, event)
            global image;
            global arr;
            global counter;
            try
                image = rgb2gray(arr{counter});
                imshow(image, 'Parent', app.UIAxes_2);
                counter = counter + 1;
                arr{counter}=image;
            catch
                uialert(app.UIFigure,"The image is already gray",'Invalid choice');
            end
        end

        % Value changed function: chooseDropDown
        function chooseDropDownValueChanged(app, event)
            value = app.chooseDropDown.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'original image')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'edited image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UPLOADButton = uibutton(app.UIFigure, 'push');
            app.UPLOADButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADButtonPushed, true);
            app.UPLOADButton.Text = 'UPLOAD';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.chooseDropDown = uidropdown(app.UIFigure);
            app.chooseDropDown.Items = {'Upsampling', 'Subsampling', 'Smoothing(Avg Standerd)', 'Smoothing(Avg Weight)', 'Smoothing(Median)', 'Smoothing(Mod)', 'Gray slicing AP#1', 'Gray slicing AP#2', 'negative', 'Log', 'inverse Log', 'Power Low', 'Contrast stretching', 'Threshould 0,1', 'Bit plane slicing', 'Addition', 'Subtraction ', 'Image Subtraction ', 'Multiplication', 'Division', 'Logic AND', 'Logic OR', 'Logic AND 1 / OR 0', 'Logic AND 0', 'Logic OR 1', 'Histogram', 'Histogram Equalization', 'Gray Level Resolution', 'Sharpining(Robert Horizontal)', 'Sharpining(Robert Vertical)', 'Sharpining(Sobel Horizontal)', 'Sharpining(Sobel Vertical)', 'Sharpining(Comp Laplacian)', 'Sharpening Robert frist dervite ', 'Sharpening Sobel frist dervite ', 'Sharpining(Laplacian)', 'Low pass filter (ideal both)', 'Low pass filter (Butterworth both)', 'Low pass filter (gaussian both)', 'High pass filter (ideal both)', 'High pass filter (Butterworth both)', 'High pass filter (gaussian both)'};
            app.chooseDropDown.Editable = 'on';
            app.chooseDropDown.ValueChangedFcn = createCallbackFcn(app, @chooseDropDownValueChanged, true);
            app.chooseDropDown.Value = 'Histogram';
            app.Input2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input1EditField = uieditfield(app.UIFigure, 'numeric');
            app.APPLYButton = uibutton(app.UIFigure, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.RGBTOGRAYButton = uibutton(app.UIFigure, 'push');
            app.RGBTOGRAYButton.ButtonPushedFcn = createCallbackFcn(app, @RGBTOGRAYButtonPushed, true);
            app.RGBTOGRAYButton.Text = 'RGBTOGRAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Omar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end